classdef kennyNAE_UHI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        TrackingHoustonsHeatIslandsLabel  matlab.ui.control.Label
        GoBacktoHomeButton  matlab.ui.control.Button
        LoaderButton        matlab.ui.control.Button
        Lamp24              matlab.ui.control.Lamp
        Lamp14              matlab.ui.control.Lamp
        Panel               matlab.ui.container.Panel
        PlotGraphLabel      matlab.ui.control.Label
        MapViewSwitch       matlab.ui.control.ToggleSwitch
        PlotGraphSwitch     matlab.ui.control.Switch
        Slider              matlab.ui.control.Slider
        TabGroup            matlab.ui.container.TabGroup
        IntroTab            matlab.ui.container.Tab
        TextArea            matlab.ui.control.TextArea
        GeoScatter          matlab.ui.container.Tab
        CitationsTab        matlab.ui.container.Tab
        Label_5             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        Lamp20              matlab.ui.control.Lamp
        Lamp16              matlab.ui.control.Lamp
    end


    properties (Access = private)
    Houston2014
    Houston2016
    Houston2020
    Houston2024
    CurrentData
    Latitude
    Longitude
    ZipCode
    SurfaceTemperature
    HeatIndex
    SelectedDataSet
    NHI
    NSTemp
    NormalizedDS
    SelectedYear = 2016
    GeoAxes
    Plotter = "Off"
    MapView
    ColorRange
    LoaderSelector = 0
    end
    
    methods (Access = public)
        
        function createGeoAxes(app)
            if app.Plotter == "On"
                app.DataColorIndexer();

                if isempty(app.GeoAxes) || ~isvalid(app.GeoAxes)
                    app.GeoAxes = geoaxes(app.GeoScatter, 'Position', [0.1 0.1 0.8 0.8]);
                    geobasemap(app.GeoAxes, "satellite");
                else
                    cla(app.GeoAxes, 'reset'); % clear previous plot instead of creating a new figure
                end

                 if app.MapView == "Temperature"
                app.SelectedDataSet = app.SurfaceTemperature;
                elseif app.MapView == "Heat Index"
                app.SelectedDataSet = app.HeatIndex;
                end

                if isempty(app.SelectedDataSet)
                app.SelectedDataSet = app.SurfaceTemperature; % default to temperature if not set
                end


                geobasemap(app.GeoAxes, "satellite");
                gscatterplot = geoscatter(app.GeoAxes, app.Latitude, app.Longitude,((((((app.NormalizedDS.*10)).^2)).*10)+30), app.SelectedDataSet, 'filled','MarkerFaceAlpha',0.8);

                clim(app.GeoAxes,app.ColorRange);
                colormap(app.GeoAxes,'turbo');
                colorbar(app.GeoAxes);

                % set up data cursor to show ZIP code & Heat Index/Temperature
                dcm = datacursormode(app.UIFigure);
                dcm.Enable = 'on';
                dcm.DisplayStyle = 'datatip'; % Ensures data tips are displayed as tooltips
                dcm.SnapToDataVertex = 'off'; % Snap cursor to nearest data point
                dcm.UpdateFcn = @(src, event) customDataTip(app, event); 
                %automatically opening geosplot tab
                app.TabGroup.SelectedTab = app.GeoScatter;
            end
        end
     

        function txt = customDataTip(app, event)
            pos = event.Position;
            allLatLon = [app.Latitude, app.Longitude];

            % get the index of the selected point. searches for exact match
            % based on event.Position
            idx = find(allLatLon(:,1) == pos(1) & allLatLon(:,2) == pos(2), 1);

            if ~isempty(idx)
                if isequal(app.SelectedDataSet, app.SurfaceTemperature)
                label = 'Temperature: ';
                elseif isequal(app.SelectedDataSet, app.HeatIndex)
                label = 'Heat Index: ';
                else
                label = 'Data: ';
                end
            txt = {['ZIP Code: ', num2str(app.ZipCode(idx))], [label, num2str(app.SelectedDataSet(idx)),'°F']};
            else
            txt = {'No data available'};
            end
        end


             function DataColorIndexer(app)
                      if app.LoaderSelector == 4
                     allMeasurements = [app.Houston2016.STemp; app.Houston2020.STemp; app.Houston2016.HI; app.Houston2020.HI;app.Houston2014.STemp;app.Houston2014.HI;app.Houston2024.STemp;app.Houston2024.HI];
                     app.ColorRange = [min(allMeasurements), max(allMeasurements)];
                      end
             end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
         if app.Slider.Value < 2018 && app.Slider.Value > 2015
            app.Slider.Value = 2016;
         elseif app.Slider.Value > 2022
             app.Slider.Value = 2024;
         elseif app.Slider.Value < 2015
             app.Slider.Value = 2014;
         else
             app.Slider.Value = 2020;
         end
         app.SelectedYear = app.Slider.Value;
         
         switch(app.SelectedYear)
             case 2014
                 app.CurrentData = app.Houston2014;
             case 2016
                 app.CurrentData = app.Houston2016;
             case 2020
                 app.CurrentData = app.Houston2020;
             case 2024
                 app.CurrentData = app.Houston2024;
             otherwise
         end
app.Latitude = app.CurrentData.Latitude;
app.Longitude = app.CurrentData.Longitude;
app.ZipCode = app.CurrentData.Zipcode;
app.SurfaceTemperature = app.CurrentData.STemp;
app.HeatIndex = app.CurrentData.HI;
app.NHI = app.CurrentData.NHI;
app.NSTemp = app.CurrentData.NSTemp;

% app.DisplayData();
createGeoAxes(app);
        end

        % Value changed function: MapViewSwitch
        function MapViewSwitchValueChanged(app, event)
            app.MapView = app.MapViewSwitch.Value;
            if app.MapView == "SurfaceTemperature"
                app.SelectedDataSet = app.SurfaceTemperature;
                app.NormalizedDS = app.NSTemp;
            elseif app.MapView == "Heat Index"
                app.SelectedDataSet = app.HeatIndex;
                app.NormalizedDS = app.NHI;
            end
            createGeoAxes(app);
            % app.DisplayData();
        end

        % Value changed function: PlotGraphSwitch
        function PlotGraphSwitchValueChanged(app, event)
            app.Plotter = app.PlotGraphSwitch.Value;
            if app.Plotter == "On"
                createGeoAxes(app);
                disp(app.CurrentData);
            end
        end

        % Button pushed function: LoaderButton
        function LoaderButtonPushed(app, event)
                ButtonLabels = ["Load 2014 Data","Load 2016 Data","Load 2020 Data","Load 2024 Data"];

                app.LoaderSelector = app.LoaderSelector + 1;

        switch(app.LoaderSelector)
            case 0
                app.LoaderButton.Text = ButtonLabels(1,1);
            case 1
                H014 = load('Houston2014.mat');
                app.Lamp14.Color = [0.00,1.00,0.00];
                app.Houston2014 = H014.H14;
                app.CurrentData = app.Houston2014;
                %app.DataColorIndexer();

                app.LoaderButton.Text = ButtonLabels(1,2);
            case 2
                H016 = load('Houston2016.mat');
                app.Lamp16.Color = [0.00,1.00,0.00];
                app.Houston2016 = H016.H16;
                app.CurrentData = app.Houston2016;
                %app.DataColorIndexer();

                app.LoaderButton.Text = ButtonLabels(1,3);
            case 3
                H020 = load('Houston2020.mat');
                app.Lamp20.Color = [0.00,1.00,0.00];
                app.Houston2020 = H020.H20;
                app.CurrentData = app.Houston2020;
                %app.DataColorIndexer();

                app.LoaderButton.Text = ButtonLabels(1,4);
            case 4
                H024 = load('Houston2024.mat');
                app.Lamp24.Color = [0.00,1.00,0.00];
                app.Houston2024 = H024.H24;
                app.CurrentData = app.Houston2024;
                app.DataColorIndexer();

                disp(app.CurrentData);

        end
        end

        % Button pushed function: GoBacktoHomeButton
        function GoBacktoHomeButtonPushed(app, event)
            app1
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Lamp16 = uilamp(app.GridLayout);
            app.Lamp20 = uilamp(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Background';
            app.TextArea = uitextarea(app.IntroTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'It''s easy to imagine global warming as an inevitability we can''t control. Like, I mean you''re not really wrong LOL.... but I also think that it''s worth looking at where we''re heading, the progress we''ve made, and what we can improve on.'; ''; 'This is a Geoscatter of Houston ZipCode, their Heat Indexes and Land Surface Temperature using NASA data I had to fight to the death for and load into QGIS and map to points. Please enjoy it.'; ''; 'The goal is to eventually track with ZipCodes in Houston have been able to slow the Urban Heat Island effect with sustainable infrastructure.'; ''; 'Load all 4 datasets first, adjust settings, and then plot graph!'};
            app.GeoScatter = uitab(app.TabGroup);
            app.GeoScatter.Title = 'GeoPlot';
            app.CitationsTab = uitab(app.TabGroup);
            app.CitationsTab.Title = 'Citations';
            app.Label = uilabel(app.CitationsTab);
            app.Label.Text = 'Wan, Z., Hook, S., Hulley, G. (2021). MODIS/Terra Land Surface Temperature/Emissivity 8-Day L3 Global 6km SIN Grid V061 [Data set]. NASA EOSDIS Land Processes Distributed Active Archive Center. Accessed 2025-03-26 from https://doi.org/10.5067/MODIS/MOD11B2.061 ';
            app.Label_2 = uilabel(app.CitationsTab);
            app.Label_2.Text = 'Wan, Z., Hook, S., Hulley, G. (2021). MODIS/Terra Land Surface Temperature/Emissivity 8-Day L3 Global 1km SIN Grid V061 [Data set]. NASA EOSDIS Land Processes Distributed Active Archive Center. Accessed 2025-03-27 from https://doi.org/10.5067/MODIS/MOD11A2.061';
            app.Label_3 = uilabel(app.CitationsTab);
            app.Label_3.Text = 'Beaudoing, H. and M. Rodell, NASA/GSFC/HSL (2020), GLDAS Noah Land Surface Model L4 3 hourly 0.25 x 0.25 degree V2.1, Greenbelt, Maryland, USA, Goddard Earth Sciences Data and Information Services Center (GES DISC), Accessed: [Data Access Date], 10.5067/E7TYRXPJKWOQ';
            app.Label_4 = uilabel(app.CitationsTab);
            app.Label_4.Text = 'ChatGPT for navigating databases and extracting data';
            app.Label_5 = uilabel(app.CitationsTab);
            app.Label_5.Text = '“US ZIP Codes Database.” Simplemaps, simplemaps.com/data/us-zips. Accessed 27 Mar. 2025. ';
            app.Panel = uipanel(app.GridLayout);
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [2014 2024];
            app.Slider.MajorTicks = [2014 2016 2020 2024];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [2014 2016 2020 2024];
            app.Slider.Value = 2020;
            app.PlotGraphSwitch = uiswitch(app.Panel, 'slider');
            app.PlotGraphSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotGraphSwitchValueChanged, true);
            app.MapViewSwitch = uiswitch(app.Panel, 'toggle');
            app.MapViewSwitch.Items = {'Temperature', 'Heat Index'};
            app.MapViewSwitch.ValueChangedFcn = createCallbackFcn(app, @MapViewSwitchValueChanged, true);
            app.MapViewSwitch.Value = 'Temperature';
            app.PlotGraphLabel = uilabel(app.Panel);
            app.PlotGraphLabel.Text = 'Plot Graph';
            app.Lamp14 = uilamp(app.GridLayout);
            app.Lamp24 = uilamp(app.GridLayout);
            app.LoaderButton = uibutton(app.GridLayout, 'push');
            app.LoaderButton.ButtonPushedFcn = createCallbackFcn(app, @LoaderButtonPushed, true);
            app.LoaderButton.Text = 'Load 2014 Data';
            app.GoBacktoHomeButton = uibutton(app.GridLayout, 'push');
            app.GoBacktoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoBacktoHomeButtonPushed, true);
            app.GoBacktoHomeButton.Text = 'Go Back to Home';
            app.TrackingHoustonsHeatIslandsLabel = uilabel(app.GridLayout);
            app.TrackingHoustonsHeatIslandsLabel.Text = 'Tracking Houston''s Heat Islands';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kennyNAE_UHI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end